classdef ship_ice < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ShipinitializationPanel       matlab.ui.container.Panel
        DimensionsPanel               matlab.ui.container.Panel
        LengthSpinnerLabel            matlab.ui.control.Label
        LengthSpinner                 matlab.ui.control.Spinner
        BreadthSpinnerLabel           matlab.ui.control.Label
        BreadthSpinner                matlab.ui.control.Spinner
        DepthSpinnerLabel             matlab.ui.control.Label
        DepthSpinner                  matlab.ui.control.Spinner
        PropulsionPanel               matlab.ui.container.Panel
        PowerKWSpinnerLabel           matlab.ui.control.Label
        PowerKWSpinner                matlab.ui.control.Spinner
        DiametermSpinnerLabel         matlab.ui.control.Label
        DiametermSpinner              matlab.ui.control.Spinner
        PitchmSpinnerLabel            matlab.ui.control.Label
        PitchmSpinner                 matlab.ui.control.Spinner
        RudderangledegSpinnerLabel    matlab.ui.control.Label
        RudderangledegSpinner         matlab.ui.control.Spinner
        InitialspeedmsSpinnerLabel    matlab.ui.control.Label
        InitialspeedmsSpinner         matlab.ui.control.Spinner
        WaterlineprofileListBoxLabel  matlab.ui.control.Label
        WaterlineprofileListBox       matlab.ui.control.ListBox
        IceinitializationPanel        matlab.ui.container.Panel
        DimensionsPanel_2             matlab.ui.container.Panel
        LengthSpinner_2Label          matlab.ui.control.Label
        LengthSpinner_2               matlab.ui.control.Spinner
        BreadthSpinner_2Label         matlab.ui.control.Label
        BreadthSpinner_2              matlab.ui.control.Spinner
        ThicknessPanel                matlab.ui.container.Panel
        MeanSpinnerLabel              matlab.ui.control.Label
        MeanSpinner                   matlab.ui.control.Spinner
        StddeviationSpinnerLabel      matlab.ui.control.Label
        StddeviationSpinner           matlab.ui.control.Spinner
        StrengthpropertyPaPanel       matlab.ui.container.Panel
        sig_cSpinnerLabel             matlab.ui.control.Label
        sig_cSpinner                  matlab.ui.control.Spinner
        sig_fSpinnerLabel             matlab.ui.control.Label
        sig_fSpinner                  matlab.ui.control.Spinner
        ESpinnerLabel                 matlab.ui.control.Label
        ESpinner                      matlab.ui.control.Spinner
        ImplementButton               matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImplementButton
        function ImplementButtonPushed(app, event)
            h = app.MeanSpinner.Value; % m
            h_std = app.StddeviationSpinner.Value; % m
            pow = app.PowerKWSpinner.Value; % kW
            pp = 100*app.PitchmSpinner.Value/4.5/1.14; % percent of max
            dis = 2000; % m
            v0 = app.InitialspeedmsSpinner.Value; % m/s
            sigc = app.sig_cSpinner.Value; % Pa
            sigc_std = 0.33*sigc; % Pa
            sigf = app.sig_fSpinner.Value; % Pa
            sigf_std = 0.2*sigf; % Pa
            E = app.ESpinner.Value; % Pa
            operationmode = 1; % 1 for self-propulsion, 2 for towing
            icecondition = 1; % 1 for level ice, 2 for channel
            v_chan = [0,0];
            vl = v_chan(1);
            vr = v_chan(2);
            scale = 1;
            rdag = app.RudderangledegSpinner.Value;
            
            %% Ship and Ice field initialize
            load Agulhashull22112019.mat%hull line and angles
            x_f = x_f/scale;
            y_f = y_f/scale;
            x_phi = x_phi/scale;
            
            vinit = v0;
            %Thrust leave as [] if not known
            Thrust = [];
            power = pow*ones(1,dis);
            prop_pitch = ones(1,dis)*pp*1.14/100;
            
            %generise ship information
            T = app.DepthSpinner.Value;
            zCOG = 8.88;
            [ship] = hull_teho(x_f, y_f, x_phi, phiIn, vinit, scale, T, zCOG);
            ship.name = 'Agu';
            %propeller
            %propeller diameter, not scaled yet
            ship.dprop = app.DiametermSpinner.Value;
            ship.fullpower = 8820;
            ship.fullpitch = 1.14;
            ship.refpitch = 1.098;%reference pitch for bollard pull calculation
            ship.itaref = -0.330*(ship.refpitch)^2+0.958*ship.refpitch +0.018;
            %assumed 9MW propulsive power and 4.5m diameter propeller, not scaled yet
            K_ref = -0.5848*ship.refpitch+3.349;
            Ke_ref = (0.250/pi^2)^(1/3)*K_ref;
            ship.bollard = 2*Ke_ref*(1/2*ship.fullpower*ship.dprop)^(2/3)*1e3;
            ship.vow = 0.6595*(ship.fullpower*ship.itaref)^(0.3064);
            
            %ship rudder information, not scaled yet
            b = 4.5;% Rudder height
            ship.Ar = 10.24;
            ship.Rasp = b^2/ship.Ar;
            ship.chord = 2;%chord length
            ship.rudpos = [57.5,zCOG-4]; % distance to CoG, distance to WL
            ship.Kr = 0.5+0.5/(1+0.15/(3.6/ship.dprop));
            
            % Ice field thickness in 1m resolution
            iceThick = h+h_std*randn(1,dis);
            % eliminate abnormally large values
            for i = 1:dis
                while iceThick(i)<h/2 || iceThick(i)>h*3/2
                    iceThick(i) = h+h_std*randn(1);
                end
            end
            ridgeThick = zeros(length(iceThick),1);%Ice ridge thickness
            
            m = app.BreadthSpinner_2.Value;
            ship.rcog = [-100/scale m-200 0 0]';
            n = app.LengthSpinner_2.Value;
            d = 15;
            n = (length(iceThick)==1)*n+(length(iceThick)>1)*length(iceThick);%iceThick is scalar when mean value is used, vector when coordinates are used
            if icecondition == 1
                [iceField] = icefield(n,m, 0, iceThick,zeros(size(iceThick)),scale,sigc,sigc_std,sigf,sigf_std,E); 
            elseif icecondition == 2
                [iceField] = channelfield(n,m, d, iceThick, scale); 
            end
            
            %% Run
            masterScript_v1(ship,iceField,h,dis,operationmode,icecondition,v_chan,scale,power,prop_pitch,Thrust,vinit,rdag)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ShipinitializationPanel = uipanel(app.UIFigure);
            app.ShipinitializationPanel.Title = 'Ship initialization';
            app.DimensionsPanel = uipanel(app.ShipinitializationPanel);
            app.DimensionsPanel.Title = 'Dimensions';
            app.LengthSpinnerLabel = uilabel(app.DimensionsPanel);
            app.LengthSpinnerLabel.Text = 'Length';
            app.LengthSpinner = uispinner(app.DimensionsPanel);
            app.LengthSpinner.Limits = [0 Inf];
            app.BreadthSpinnerLabel = uilabel(app.DimensionsPanel);
            app.BreadthSpinnerLabel.Text = 'Breadth';
            app.BreadthSpinner = uispinner(app.DimensionsPanel);
            app.BreadthSpinner.Limits = [0 Inf];
            app.DepthSpinnerLabel = uilabel(app.DimensionsPanel);
            app.DepthSpinnerLabel.Text = 'Depth';
            app.DepthSpinner = uispinner(app.DimensionsPanel);
            app.DepthSpinner.Limits = [0 Inf];
            app.PropulsionPanel = uipanel(app.ShipinitializationPanel);
            app.PropulsionPanel.Title = 'Propulsion';
            app.PowerKWSpinnerLabel = uilabel(app.PropulsionPanel);
            app.PowerKWSpinnerLabel.Text = 'Power KW';
            app.PowerKWSpinner = uispinner(app.PropulsionPanel);
            app.PowerKWSpinner.Limits = [0 Inf];
            app.DiametermSpinnerLabel = uilabel(app.PropulsionPanel);
            app.DiametermSpinnerLabel.Text = 'Diameter m';
            app.DiametermSpinner = uispinner(app.PropulsionPanel);
            app.DiametermSpinner.Limits = [0 Inf];
            app.PitchmSpinnerLabel = uilabel(app.PropulsionPanel);
            app.PitchmSpinnerLabel.Text = 'Pitch m';
            app.PitchmSpinner = uispinner(app.PropulsionPanel);
            app.PitchmSpinner.Limits = [0 Inf];
            app.RudderangledegSpinnerLabel = uilabel(app.PropulsionPanel);
            app.RudderangledegSpinnerLabel.Text = 'Rudder angle deg';
            app.RudderangledegSpinner = uispinner(app.PropulsionPanel);
            app.InitialspeedmsSpinnerLabel = uilabel(app.ShipinitializationPanel);
            app.InitialspeedmsSpinnerLabel.Text = 'Initial speed m/s';
            app.InitialspeedmsSpinner = uispinner(app.ShipinitializationPanel);
            app.InitialspeedmsSpinner.Limits = [0 Inf];
            app.WaterlineprofileListBoxLabel = uilabel(app.ShipinitializationPanel);
            app.WaterlineprofileListBoxLabel.Text = 'Waterline profile';
            app.WaterlineprofileListBox = uilistbox(app.ShipinitializationPanel);
            app.WaterlineprofileListBox.Items = {'Profile 1', 'Profile 2'};
            app.WaterlineprofileListBox.Value = 'Profile 1';
            app.IceinitializationPanel = uipanel(app.UIFigure);
            app.IceinitializationPanel.Title = 'Ice initialization';
            app.DimensionsPanel_2 = uipanel(app.IceinitializationPanel);
            app.DimensionsPanel_2.Title = 'Dimensions';
            app.LengthSpinner_2Label = uilabel(app.DimensionsPanel_2);
            app.LengthSpinner_2Label.Text = 'Length';
            app.LengthSpinner_2 = uispinner(app.DimensionsPanel_2);
            app.LengthSpinner_2.Limits = [0 Inf];
            app.BreadthSpinner_2Label = uilabel(app.DimensionsPanel_2);
            app.BreadthSpinner_2Label.Text = 'Breadth';
            app.BreadthSpinner_2 = uispinner(app.DimensionsPanel_2);
            app.BreadthSpinner_2.Limits = [0 Inf];
            app.ThicknessPanel = uipanel(app.IceinitializationPanel);
            app.ThicknessPanel.Title = 'Thickness';
            app.MeanSpinnerLabel = uilabel(app.ThicknessPanel);
            app.MeanSpinnerLabel.Text = 'Mean';
            app.MeanSpinner = uispinner(app.ThicknessPanel);
            app.MeanSpinner.Limits = [0 Inf];
            app.StddeviationSpinnerLabel = uilabel(app.ThicknessPanel);
            app.StddeviationSpinnerLabel.Text = 'Std. deviation';
            app.StddeviationSpinner = uispinner(app.ThicknessPanel);
            app.StddeviationSpinner.Limits = [0 Inf];
            app.StrengthpropertyPaPanel = uipanel(app.IceinitializationPanel);
            app.StrengthpropertyPaPanel.Title = 'Strength property (Pa)';
            app.sig_cSpinnerLabel = uilabel(app.StrengthpropertyPaPanel);
            app.sig_cSpinnerLabel.Text = 'sig_c';
            app.sig_cSpinner = uispinner(app.StrengthpropertyPaPanel);
            app.sig_cSpinner.Limits = [0 Inf];
            app.sig_cSpinner.Value = 2000000;
            app.sig_fSpinnerLabel = uilabel(app.StrengthpropertyPaPanel);
            app.sig_fSpinnerLabel.Text = 'sig_f';
            app.sig_fSpinner = uispinner(app.StrengthpropertyPaPanel);
            app.sig_fSpinner.Limits = [0 Inf];
            app.sig_fSpinner.Value = 500000;
            app.ESpinnerLabel = uilabel(app.StrengthpropertyPaPanel);
            app.ESpinnerLabel.Text = 'E';
            app.ESpinner = uispinner(app.StrengthpropertyPaPanel);
            app.ESpinner.Limits = [0 Inf];
            app.ESpinner.Value = 5000000000;
            app.ImplementButton = uibutton(app.UIFigure, 'push');
            app.ImplementButton.ButtonPushedFcn = createCallbackFcn(app, @ImplementButtonPushed, true);
            app.ImplementButton.Text = 'Implement';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Trajectory monitor')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ship_ice

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end